classdef PRoyectofinal_OA_LJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        PuertoComEditFieldLabel        matlab.ui.control.Label
        PuertoComEditField             matlab.ui.control.EditField
        ConectarButton                 matlab.ui.control.Button
        TiempodesenialmuestradasEditFieldLabel  matlab.ui.control.Label
        TiempodesenialmuestradasEditField  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
        GananciaLabel                  matlab.ui.control.Label
        GananciaEditField              matlab.ui.control.NumericEditField
        AplicarFiltroButton            matlab.ui.control.Button
        FrecunciadecorteHzEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzEditField    matlab.ui.control.NumericEditField
        FiltrosDropDownLabel           matlab.ui.control.Label
        FiltrosDropDown                matlab.ui.control.DropDown
        TipodeFiltrosDropDownLabel     matlab.ui.control.Label
        TipodeFiltrosDropDown          matlab.ui.control.DropDown
        FormadelfiltroSwitchLabel      matlab.ui.control.Label
        FormadelfiltroSwitch           matlab.ui.control.RockerSwitch
        OrdendefiltroEditFieldLabel    matlab.ui.control.Label
        OrdendefiltroEditField         matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        Tab3                           matlab.ui.container.Tab
        GuardarButton                  matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        TiempodeGrabacionLabel         matlab.ui.control.Label
        Propiedadesparaguardar___________Label  matlab.ui.control.Label
        UnidadesdeTiempoDropDownLabel  matlab.ui.control.Label
        UnidadesdeTiempoDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Puerto_serial
        tiempo_de_senial
        senial
%         tab 2
        formadefiltros
        filtros
        tiposdefiltros
        frecuanciadecorte
        orden
        
        
        fc
        Wn
        senial_filtrada
       
        h
        w
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PuertoComEditField
        function PuertoComEditFieldValueChanged(app, event)
            app.Puerto_serial = app.PuertoComEditField.Value;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global fs;
            tiva = serialport(app.Puerto_serial, 1000000); %el puerto puede variar entre computadores
            %frecuecia de muestreo en Hz.
            fs = 1000;
            % vectores de datos 
            N = fs*app.TiempodesenialmuestradasEditField.Value;
            app.senial = zeros(1,N);
            n = 1:N;
            flush(tiva);
            for i=n
            app.senial(i) = str2double(readline(tiva));
            end 
            clear tiva
            plot(app.UIAxes,n,app.senial)
        end

        % Value changed function: FormadelfiltroSwitch
        function FormadelfiltroSwitchValueChanged(app, event)
           app.formadefiltros = app.FormadelfiltroSwitch.Value; 
           
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            app.filtros = app.FiltrosDropDown.Value;
            
        end

        % Value changed function: TipodeFiltrosDropDown
        function TipodeFiltrosDropDownValueChanged(app, event)
            app.tiposdefiltros = app.TipodeFiltrosDropDown.Value;
            
        end

        % Value changed function: OrdendefiltroEditField
        function OrdendefiltroEditFieldValueChanged(app, event)
            app.orden = app.OrdendefiltroEditField.Value;
           
            
        end

        % Value changed function: FrecunciadecorteHzEditField
        function FrecunciadecorteHzEditFieldValueChanged(app, event)
            app.frecuanciadecorte = app.FrecunciadecorteHzEditField.Value;
            
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            global a;
           
            global b;
            global fs;
            app.Wn=0.5;
            fs = 1000;
            if(strcmp(app.formadefiltros,"IIR"))
                
                if (strcmp(app.tiposdefiltros,"Butterworth"))
                    app.Wn=app.frecuanciadecorte/(2*fs);
                    [a,b]=butter((app.orden),(app.Wn),(app.filtros));
                    [app.h,app.w]=freqz(a,b);
                    plot (app.UIAxes2,app.w,abs(app.h));
                end
                
                
                if (strcmp(app.tiposdefiltros,"Chebyshev"))
                    app.Wn=app.fc*(fs/2);
                    %esta no estoy seguro 
                    [a,b]=cheby1((app.orden),Rp,(app.Wn),(app.filtros));
                    freqz(b,a)
                    app.senial_filtrada=filter(b,a,app.senial);
                end
%                 if (strcmp(app.tiposdefiltros.Value,"Elliptic"))
%                     app.Wn=app.fc*(fs/2);
%                     
%                     freqz(b,a)
%                     app.senial_filtrada=filter(b,a,app.senial);
%                 end
            end
            %selector en ffr
%             if(strcmp(app.formadefiltros,"FIR"))
%                 
%                 if (strcmp(app.TipodeFiltrosDropDown.Value,"Butterworth"))
%                     app.Wn=app.fc*(fs/2);
%                     [a,b]=butter(app.orden,app.Wn,app.filtros);
%                 end
%                 if (strcmp(app.TipodeFiltrosDropDown.Value,"Chebyshev"))
%                     app.Wn=app.fc*(fs/2);
%                 end
%                 if (strcmp(app.TipodeFiltrosDropDown.Value,"Elliptic"))
%                     app.Wn=app.fc*(fs/2);
%                 end
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PuertoComEditFieldLabel = uilabel(app.Tab);
            app.PuertoComEditFieldLabel.Text = 'Puerto Com';
            app.PuertoComEditField = uieditfield(app.Tab, 'text');
            app.PuertoComEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoComEditFieldValueChanged, true);
            app.ConectarButton = uibutton(app.Tab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.TiempodesenialmuestradasEditFieldLabel = uilabel(app.Tab);
            app.TiempodesenialmuestradasEditFieldLabel.Text = 'Tiempo de senial muestrada (s) ';
            app.TiempodesenialmuestradasEditField = uieditfield(app.Tab, 'numeric');
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GananciaLabel = uilabel(app.Tab2);
            app.GananciaLabel.Text = 'Ganancia          ';
            app.GananciaEditField = uieditfield(app.Tab2, 'numeric');
            app.AplicarFiltroButton = uibutton(app.Tab2, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro ';
            app.FrecunciadecorteHzEditFieldLabel = uilabel(app.Tab2);
            app.FrecunciadecorteHzEditFieldLabel.Text = 'Frecuncia de corte (Hz)';
            app.FrecunciadecorteHzEditField = uieditfield(app.Tab2, 'numeric');
            app.FrecunciadecorteHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecunciadecorteHzEditFieldValueChanged, true);
            app.FiltrosDropDownLabel = uilabel(app.Tab2);
            app.FiltrosDropDownLabel.Text = 'Filtros ';
            app.FiltrosDropDown = uidropdown(app.Tab2);
            app.FiltrosDropDown.Items = {'low', 'bandpass', 'high'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = 'low';
            app.TipodeFiltrosDropDownLabel = uilabel(app.Tab2);
            app.TipodeFiltrosDropDownLabel.Text = 'Tipo de Filtros ';
            app.TipodeFiltrosDropDown = uidropdown(app.Tab2);
            app.TipodeFiltrosDropDown.Items = {'Butterworth', 'Chebyshev', 'Elliptic'};
            app.TipodeFiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeFiltrosDropDownValueChanged, true);
            app.TipodeFiltrosDropDown.Value = 'Elliptic';
            app.FormadelfiltroSwitchLabel = uilabel(app.Tab2);
            app.FormadelfiltroSwitchLabel.Text = 'Forma del filtro ';
            app.FormadelfiltroSwitch = uiswitch(app.Tab2, 'rocker');
            app.FormadelfiltroSwitch.Items = {'IIR', 'FIR'};
            app.FormadelfiltroSwitch.ValueChangedFcn = createCallbackFcn(app, @FormadelfiltroSwitchValueChanged, true);
            app.FormadelfiltroSwitch.Value = 'IIR';
            app.OrdendefiltroEditFieldLabel = uilabel(app.Tab2);
            app.OrdendefiltroEditFieldLabel.Text = 'Orden de filtro';
            app.OrdendefiltroEditField = uieditfield(app.Tab2, 'numeric');
            app.OrdendefiltroEditField.ValueChangedFcn = createCallbackFcn(app, @OrdendefiltroEditFieldValueChanged, true);
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.GuardarButton = uibutton(app.Tab3, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.Slider = uislider(app.Tab3);
            app.Slider.Limits = [0 10];
            app.TiempodeGrabacionLabel = uilabel(app.Tab3);
            app.TiempodeGrabacionLabel.Text = 'Tiempo de Grabacion ';
            app.Propiedadesparaguardar___________Label = uilabel(app.Tab3);
            app.Propiedadesparaguardar___________Label.Text = '     __________ Propiedades para guardar  ___________';
            app.UnidadesdeTiempoDropDownLabel = uilabel(app.Tab3);
            app.UnidadesdeTiempoDropDownLabel.Text = 'Unidades de Tiempo ';
            app.UnidadesdeTiempoDropDown = uidropdown(app.Tab3);
            app.UnidadesdeTiempoDropDown.Items = {'segundos ', 'Milisegundos', 'Microsegundos'};
            app.UnidadesdeTiempoDropDown.Value = 'Milisegundos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRoyectofinal_OA_LJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end